classdef fract2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ControlAdaptivButton          matlab.ui.control.Button
        FractaliJuliaDropDown         matlab.ui.control.DropDown
        FractaliJuliaDropDownLabel    matlab.ui.control.Label
        RezolutieSlider               matlab.ui.control.Slider
        RezoluiefractalSliderLabel    matlab.ui.control.Label
        TextAreaFractalStats          matlab.ui.control.TextArea
        InfoTextAreaLabel             matlab.ui.control.Label
        AnimatieJuliaButton           matlab.ui.control.Button
        SalveazafractalButton         matlab.ui.control.Button
        PIDlaFractalButton            matlab.ui.control.Button
        FractallaPIDButton            matlab.ui.control.Button
        DEditField                    matlab.ui.control.NumericEditField
        DEditFieldLabel               matlab.ui.control.Label
        IEditField                    matlab.ui.control.NumericEditField
        IEditFieldLabel               matlab.ui.control.Label
        PEditField                    matlab.ui.control.NumericEditField
        PEditFieldLabel               matlab.ui.control.Label
        TipFractalButtonGroup         matlab.ui.container.ButtonGroup
        MandelbrotButton              matlab.ui.control.ToggleButton
        JuliaButton                   matlab.ui.control.ToggleButton
        GenerareFractalButton         matlab.ui.control.Button
        ParteImaginaraEditField       matlab.ui.control.NumericEditField
        ParteImaginaraEditFieldLabel  matlab.ui.control.Label
        PartearealaEditField          matlab.ui.control.NumericEditField
        PartearealaEditFieldLabel     matlab.ui.control.Label
        UIAxesPID                     matlab.ui.control.UIAxes
        UIAxesFractali                matlab.ui.control.UIAxes
    end

    
    methods (Access = private)

        function res = getFractalResolution(app)
            res = round(app.RezolutieSlider.Value);
        end

        function img = generateJulia(app, c_real, c_imag)
            maxIter = 200;
            xlim = [-1.5, 1.5];
            ylim = [-1.5, 1.5];
        
            res = app.getFractalResolution();  
        
            x = linspace(xlim(1), xlim(2), res);
            y = linspace(ylim(1), ylim(2), res);
            [X, Y] = meshgrid(x, y);
            Z = X + 1i*Y;
            C = c_real + 1i*c_imag;
        
            img = zeros(size(Z));
            for k = 1:maxIter
                Z = Z.^2 + C;
                img = img + (abs(Z) < 2); 
            end
        
            img = app.normalizeImage(img);
        end


        function img = generateMandelbrot(app)
            maxIter = 200;
            xlim = [-2, 1];
            ylim = [-1.5, 1.5];
        
            res = app.getFractalResolution(); 
        
            x = linspace(xlim(1), xlim(2), res);
            y = linspace(ylim(1), ylim(2), res);
            [X, Y] = meshgrid(x, y);
            C = X + 1i*Y;
            Z = zeros(size(C));
            img = zeros(size(C));
        
            for k = 1:maxIter
                Z = Z.^2 + C;
                mask = abs(Z) < 2;% creează o matrice logică (mască booleană)
                img = img + mask;
                Z(~mask) = NaN;  %foloseset indexarea pentru optimizarea calculelor
            end
        
            img = app.normalizeImage(img);
        end


        function img_norm = normalizeImage(app, img)
            img = double(img);
            img_norm = (img - min(img(:))) / (max(img(:)) - min(img(:)));
        end


        function e = entropyImage(app, img)
            img = uint8(255 * app.normalizeImage(img));
            counts = imhist(img);
            counts = counts / sum(counts); % normalizarea numărului de histograme
            counts(counts == 0) = [];
            e = -sum(counts .* log2(counts)); %Shannon pt entropie
        end

        
        function v = varianceImage(app, img)
            img = app.normalizeImage(img);
            v = var(img(:));
        end

        
        function [P, I, D] = extractPIDfromFractal(app, img)
            e = app.entropyImage(img);
            v = app.varianceImage(img);
        
            P = round(10 * e / log2(256), 2);  
            I = round(10 * v, 2);
            D = round(10 * abs(P - I), 2);
        end
        
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ParteImaginaraEditField
        function ParteImaginaraEditFieldValueChanged(app, event)
            value = app.ParteImaginaraEditField.Value;
            
        end

        % Value changed function: PartearealaEditField
        function PartearealaEditFieldValueChanged(app, event)
            value = app.PartearealaEditField.Value;
            
        end

        % Selection changed function: TipFractalButtonGroup
        function TipFractalButtonGroupSelectionChanged(app, event)
            selectedButton = app.TipFractalButtonGroup.SelectedObject;
            
        end

        % Button pushed function: GenerareFractalButton
        function GenerareFractalButtonPushed(app, event)
            c_real = app.PartearealaEditField.Value;
            c_imag = app.ParteImaginaraEditField.Value;
       
            fractalType = strtrim(app.TipFractalButtonGroup.SelectedObject.Text);  % ← elimină spații

            switch fractalType
                case 'Julia'
                    img = app.generateJulia(c_real, c_imag);
                case 'Mandelbrot'
                    img = app.generateMandelbrot();
                otherwise
                    uialert(app.UIFigure, ['Tip fractal necunoscut: ' fractalType], 'Eroare');
                    return;
            end


            imshow(img, 'Parent', app.UIAxesFractali);      
            colormap(app.UIAxesFractali, parula);             % Sau 'jet', 'hot', 'turbo' etc.
            colorbar(app.UIAxesFractali);                     
            % Calculează entropie și variație
            e = app.entropyImage(img);
            v = app.varianceImage(img);
            
            % Scrie rezultatele în TextArea
            app.TextAreaFractalStats.Value = {
                sprintf('Entropie: %.4f', e), 
                sprintf('Variație: %.4f', v)
            };

            
        end

        % Value changed function: PEditField
        function PEditFieldValueChanged(app, event)
            value = app.PEditField.Value;
            
        end

        % Value changed function: IEditField
        function IEditFieldValueChanged(app, event)
            value = app.IEditField.Value;
            
        end

        % Value changed function: DEditField
        function DEditFieldValueChanged(app, event)
            value = app.DEditField.Value;
            
        end

        % Button pushed function: FractallaPIDButton
        function FractallaPIDButtonPushed(app, event)
            c_real = app.PartearealaEditField.Value;
            c_imag = app.ParteImaginaraEditField.Value;
        
            img = app.generateJulia(c_real, c_imag);
        
            [P, I, D] = app.extractPIDfromFractal(img);
        
            app.PEditField.Value = P;
            app.IEditField.Value = I;
            app.DEditField.Value = D;
        
           % Simulare PID real pe un sistem de ordin 2 (ex:  G(s) = 1 / (s^2 + 2s + 1))
            s = tf('s');
            G = 1 / (s^2 + 2*s + 1);
            
            % Controller PID
            C = P + I/s + D*s;
            
            % Sistem în buclă închisă
            T = feedback(C*G, 1);
            
            % Simulare răspuns la treaptă
            t = 0:0.01:10;
            [y, t] = step(T, t);
            
            % Afișare grafic
            plot(app.UIAxesPID, t, y, 'LineWidth', 1.5);
            title(app.UIAxesPID, 'Răspuns la treaptă al sistemului controlat cu PID');
            xlabel(app.UIAxesPID, 'Timp [s]');
            ylabel(app.UIAxesPID, 'Răspuns');
            grid(app.UIAxesPID, 'on');

        end

        % Button pushed function: PIDlaFractalButton
        function PIDlaFractalButtonPushed(app, event)
            Kp = app.PEditField.Value;
            Ki = app.IEditField.Value;
            Kd = app.DEditField.Value;
        
            c_real = (Kp - Ki)/10;
            c_imag = (Ki - Kd)/10;
        
            app.PartearealaEditField.Value = c_real;
            app.ParteImaginaraEditField.Value = c_imag;
        
            img = app.generateJulia(c_real, c_imag);
            imshow(img, 'Parent', app.UIAxesFractali);        % Afișează imaginea
            colormap(app.UIAxesFractali, parula);             % Sau 'jet', 'hot', 'turbo' etc.
            colorbar(app.UIAxesFractali);                     % (opțional) adaugă bară de culori

        end

        % Button pushed function: SalveazafractalButton
        function SalveazafractalButtonPushed(app, event)
            c_real = app.PartearealaEditField.Value;
            c_imag = app.ParteImaginaraEditField.Value;
            fractalType = app.TipFractalButtonGroup.SelectedObject.Text;
        
            switch fractalType
                case 'Julia'
                    img = app.generateJulia(c_real, c_imag);
                case 'Mandelbrot'
                    img = app.generateMandelbrot();
            end
        
            img_norm = app.normalizeImage(img);
        
            timestamp = string(datetime('now', 'Format', 'yyyy_MM_dd_HH_mm_ss'));
            filename = sprintf('fractal_%s_%s.png', lower(fractalType), timestamp);
        
            imwrite(img_norm, parula(256), filename);
            uialert(app.UIFigure, ['Imagine salvată ca: ' filename], 'Succes');
        end

        % Button pushed function: AnimatieJuliaButton
        function AnimatieJuliaButtonPushed(app, event)
            % Interval pentru c_real și c_imag
            t = linspace(0, 2*pi, 100);
            radius = 0.7885;  % Rază pentru stabilitate (poți modifica)
        
            for k = 1:length(t)
                % Modifică parametrii c în cerc
                c_real = radius * cos(t(k));
                c_imag = radius * sin(t(k));
        
                img = app.generateJulia(c_real, c_imag);
                imshow(img, 'Parent', app.UIAxesFractali);
                colormap(app.UIAxesFractali, parula);
                drawnow;
        
                % Oprește animația dacă fereastra se închide
                if ~isvalid(app.UIAxesFractali)
                    break;
                end
            end
        end

        % Value changed function: FractaliJuliaDropDown
        function FractaliJuliaDropDownValueChanged(app, event)
            selection = app.FractaliJuliaDropDown.Value;

                switch selection
                    case 'Clasic'
                        app.PartearealaEditField.Value = -0.7;
                        app.ParteImaginaraEditField.Value = 0.27015;
                    case 'Swirl'
                        app.PartearealaEditField.Value = 0.355;
                        app.ParteImaginaraEditField.Value = 0.355;
                    case 'Spirală'
                        app.PartearealaEditField.Value = 0.285;
                        app.ParteImaginaraEditField.Value = 0.01;
                    case 'Fluture'
                        app.PartearealaEditField.Value = -0.8;
                        app.ParteImaginaraEditField.Value = 0.156;
                    otherwise
                        return; % Nu face nimic dacă nu e o alegere validă
                end
            
                % Selectează butonul 'Julia' în grupul de tip fractal (dacă nu e deja)
                app.TipFractalButtonGroup.SelectedObject = app.JuliaButton;  % ← numele butonului Julia
            
                % Generează fractalul automat
                app.GenerareFractalButtonPushed();
                        
        end

        % Button pushed function: ControlAdaptivButton
        function ControlAdaptivButtonPushed(app, event)
             
                c_real_init = rand() * 2 - 1;  
                c_imag_init = rand() * 2 - 1;
            
                img_init = app.generateJulia(c_real_init, c_imag_init);
            
                
                e_init = app.entropyImage(img_init);
                v_init = app.varianceImage(img_init);
            
                
                [P, I, D] = app.extractPIDfromFractal(img_init);
            
                
                img_adapted = app.generateJulia(P, I); 
            
               
                imshow(img_adapted, 'Parent', app.UIAxesFractali);
                colormap(app.UIAxesFractali, turbo);   % sau 'parula', 'hot'
                colorbar(app.UIAxesFractali);
            
                % 6. Scrie informații în TextArea
                app.TextAreaFractalStats.Value = {
                    sprintf('Fractal inițial: Julia (%.2f + %.2fi)', c_real_init, c_imag_init)
                    sprintf('Entropie inițială: %.4f', e_init)
                    sprintf('Variație inițială: %.4f', v_init)
                    sprintf('Parametri PID adaptați:')
                    sprintf('P = %.2f', P)
                    sprintf('I = %.2f', I)
                    sprintf('D = %.2f', D)
                    sprintf('Fractal adaptat: Julia (P + I*i)')
                };
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesFractali = uiaxes(app.UIFigure);
            title(app.UIAxesFractali, 'Fractali')
            xlabel(app.UIAxesFractali, 'X')
            ylabel(app.UIAxesFractali, 'Y')
            zlabel(app.UIAxesFractali, 'Z')
            app.UIAxesPID = uiaxes(app.UIFigure);
            title(app.UIAxesPID, 'PID adaptiv')
            xlabel(app.UIAxesPID, 'X')
            ylabel(app.UIAxesPID, 'Y')
            zlabel(app.UIAxesPID, 'Z')
            app.PartearealaEditFieldLabel = uilabel(app.UIFigure);
            app.PartearealaEditFieldLabel.Text = 'Partea reala';
            app.PartearealaEditField = uieditfield(app.UIFigure, 'numeric');
            app.PartearealaEditField.ValueChangedFcn = createCallbackFcn(app, @PartearealaEditFieldValueChanged, true);
            app.PartearealaEditField.Value = -0.7269;
            app.ParteImaginaraEditFieldLabel = uilabel(app.UIFigure);
            app.ParteImaginaraEditFieldLabel.Text = 'Parte  Imaginara';
            app.ParteImaginaraEditField = uieditfield(app.UIFigure, 'numeric');
            app.ParteImaginaraEditField.ValueChangedFcn = createCallbackFcn(app, @ParteImaginaraEditFieldValueChanged, true);
            app.ParteImaginaraEditField.Value = 0.1889;
            app.GenerareFractalButton = uibutton(app.UIFigure, 'push');
            app.GenerareFractalButton.ButtonPushedFcn = createCallbackFcn(app, @GenerareFractalButtonPushed, true);
            app.GenerareFractalButton.Text = 'Generare Fractal';
            app.TipFractalButtonGroup = uibuttongroup(app.UIFigure);
            app.TipFractalButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TipFractalButtonGroupSelectionChanged, true);
            app.TipFractalButtonGroup.Title = 'Tip Fractal';
            app.JuliaButton = uitogglebutton(app.TipFractalButtonGroup);
            app.JuliaButton.Text = 'Julia';
            app.JuliaButton.Value = true;
            app.MandelbrotButton = uitogglebutton(app.TipFractalButtonGroup);
            app.MandelbrotButton.Text = 'Mandelbrot';
            app.PEditFieldLabel = uilabel(app.UIFigure);
            app.PEditFieldLabel.Text = 'P';
            app.PEditField = uieditfield(app.UIFigure, 'numeric');
            app.PEditField.ValueChangedFcn = createCallbackFcn(app, @PEditFieldValueChanged, true);
            app.IEditFieldLabel = uilabel(app.UIFigure);
            app.IEditFieldLabel.Text = 'I';
            app.IEditField = uieditfield(app.UIFigure, 'numeric');
            app.IEditField.ValueChangedFcn = createCallbackFcn(app, @IEditFieldValueChanged, true);
            app.DEditFieldLabel = uilabel(app.UIFigure);
            app.DEditFieldLabel.Text = 'D';
            app.DEditField = uieditfield(app.UIFigure, 'numeric');
            app.DEditField.ValueChangedFcn = createCallbackFcn(app, @DEditFieldValueChanged, true);
            app.FractallaPIDButton = uibutton(app.UIFigure, 'push');
            app.FractallaPIDButton.ButtonPushedFcn = createCallbackFcn(app, @FractallaPIDButtonPushed, true);
            app.FractallaPIDButton.Text = 'Fractal la PID';
            app.PIDlaFractalButton = uibutton(app.UIFigure, 'push');
            app.PIDlaFractalButton.ButtonPushedFcn = createCallbackFcn(app, @PIDlaFractalButtonPushed, true);
            app.PIDlaFractalButton.Text = 'PID la Fractal';
            app.SalveazafractalButton = uibutton(app.UIFigure, 'push');
            app.SalveazafractalButton.ButtonPushedFcn = createCallbackFcn(app, @SalveazafractalButtonPushed, true);
            app.SalveazafractalButton.Text = 'Salveaza fractal';
            app.AnimatieJuliaButton = uibutton(app.UIFigure, 'push');
            app.AnimatieJuliaButton.ButtonPushedFcn = createCallbackFcn(app, @AnimatieJuliaButtonPushed, true);
            app.AnimatieJuliaButton.Text = 'Animatie Julia';
            app.InfoTextAreaLabel = uilabel(app.UIFigure);
            app.InfoTextAreaLabel.Text = 'Info';
            app.TextAreaFractalStats = uitextarea(app.UIFigure);
            app.TextAreaFractalStats.Tag = 'TextAreaFractalStats';
            app.RezoluiefractalSliderLabel = uilabel(app.UIFigure);
            app.RezoluiefractalSliderLabel.Text = 'Rezoluție fractal';
            app.RezolutieSlider = uislider(app.UIFigure);
            app.RezolutieSlider.Limits = [200 1000];
            app.RezolutieSlider.MajorTicks = [200 400 600 800 1000];
            app.RezolutieSlider.Value = 500;
            app.FractaliJuliaDropDownLabel = uilabel(app.UIFigure);
            app.FractaliJuliaDropDownLabel.Text = 'Fractali Julia';
            app.FractaliJuliaDropDown = uidropdown(app.UIFigure);
            app.FractaliJuliaDropDown.Items = {'Selectează', 'Clasic', 'Swirl', 'Spirală', 'Fluture'};
            app.FractaliJuliaDropDown.ValueChangedFcn = createCallbackFcn(app, @FractaliJuliaDropDownValueChanged, true);
            app.FractaliJuliaDropDown.Value = 'Selectează';
            app.ControlAdaptivButton = uibutton(app.UIFigure, 'push');
            app.ControlAdaptivButton.ButtonPushedFcn = createCallbackFcn(app, @ControlAdaptivButtonPushed, true);
            app.ControlAdaptivButton.Text = 'Control Adaptiv';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fract2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end